classdef GUI_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PinsLabel                   matlab.ui.control.Label
        PortLabel                   matlab.ui.control.Label
        RunTimeEditField            matlab.ui.control.NumericEditField
        RunTimeEditFieldLabel       matlab.ui.control.Label
        GraphResetLamp              matlab.ui.control.Lamp
        GraphResetLampLabel         matlab.ui.control.Label
        InitializeButton            matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ValuesReadLamp              matlab.ui.control.Lamp
        ValuesReadLampLabel         matlab.ui.control.Label
        SystemInitializedLamp       matlab.ui.control.Lamp
        ReadValuesButton            matlab.ui.control.Button
        SystemInitializedLampLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Property % Description
        ard = [];
        ultras = [];
        data = [];
        filt = [];
        time = [];
        s = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeHardware(app, event)
            try
                % Create an Arduino object
                app.ard = arduino('COM4', 'Uno', 'Libraries', {'Ultrasonic','Servo'});
                % Create an ultrasonic sensor object
                app.ultras = app.ard.ultrasonic('D7', 'D6');
                % Create servo object
                app.s = app.ard.servo('D9');
                msgbox("Initialized successful");
                app.s.writePosition(0);
                
                app.PortLabel.Text = 'Port: COM4';
                app.PinsLabel.Text = 'Pins: D7,D6,D9';

            catch
                msgbox('Error initializing');
                app.GraphResetLamp.Color = 'r';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes); %reset should clear the plot completely

            app.data = [];
            app.time = [];

            app.GraphResetLamp.Color = 'g';
        end

        % Button pushed function: ReadValuesButton
        function ReadValues(app, event)
            % Initializing timer/counter    
            start = tic(); % Starting time
            i = 1;
            done = false;


            % Main to read data values from sensor
            while (~done)
                value = app.ultras.readDistance();  % Activates ultrasonic sensor to start reading
                if value ~= inf
                    app.data(i) = value; % Creating array to store data
                    app.time(i) = toc(start); % Creates array for time values
                    %drawnow;
                    writeDigitalPin(app.ard,'D13',1); % Turns the green LED on, 0 is true
                    writeDigitalPin(app.ard,'D12',0);
                    app.ValuesReadLamp.Color = 'green'; % Turns on green lamp on GUI

                    if value > .1 % Out of range
                        writeDigitalPin(app.ard,'D12',1); % Turns the red LED on, 0 is true
                        app.ValuesReadLamp.Color = 'red'; % Turns on red lamp on GUI
                        continue
                    end

                    if value < .09 % Max distance read
                        theta = .095/(.04*pi) - app.data(i)/(.04*pi); % Calculating the angle of arduino to move
                        writePosition(app.s,theta); % Writting position to servo to move
                    end

                    disp(app.data(i))

                    if app.time(i) >= app.RunTimeEditField.Value %Stopping at specified time from input field
                        done = true;
                        %disp(app.time(i))
                    end
                    i = i + 1;
                end
            end

            % Filtering data and plotting on same graph w/ raw data
            plot(app.UIAxes,app.time,app.data,'b')
            drawnow; % Updating graph continuously
            hold(app.UIAxes,'on')
            app.filt = medfilt1(app.data);
            plot(app.UIAxes,app.time,app.filt,'r')
            drawnow; % Updating filtered graph continuously
            hold(app.UIAxes,'off')

            % Turns off all LED
            writeDigitalPin(app.ard,'D12',0) 
            writeDigitalPin(app.ard,'D13',0)
        end

        % Value changed function: RunTimeEditField
        function RunTime(app, event)
            value = app.RunTimeEditField.Value; % Allows input for specific time to run
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ultrasound')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Distance(m)')
            zlabel(app.UIAxes, 'Z')
            app.SystemInitializedLampLabel = uilabel(app.UIFigure);
            app.SystemInitializedLampLabel.Text = 'System Initialized';
            app.ReadValuesButton = uibutton(app.UIFigure, 'push');
            app.ReadValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadValues, true);
            app.ReadValuesButton.Text = 'Read Values';
            app.SystemInitializedLamp = uilamp(app.UIFigure);
            app.ValuesReadLampLabel = uilabel(app.UIFigure);
            app.ValuesReadLampLabel.Text = 'Values Read';
            app.ValuesReadLamp = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeHardware, true);
            app.InitializeButton.Text = 'Initialize';
            app.GraphResetLampLabel = uilabel(app.UIFigure);
            app.GraphResetLampLabel.Text = 'Graph Reset';
            app.GraphResetLamp = uilamp(app.UIFigure);
            app.RunTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RunTimeEditFieldLabel.Text = 'Run Time';
            app.RunTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RunTime, true);
            app.PortLabel = uilabel(app.UIFigure);
            app.PortLabel.Text = 'Port';
            app.PinsLabel = uilabel(app.UIFigure);
            app.PinsLabel.Text = 'Pins';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end