classdef GUI_NumericalMethod_Implementation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CalibrateGraphButton        matlab.ui.control.Button
        PinsLabel                   matlab.ui.control.Label
        PortLabel                   matlab.ui.control.Label
        RunTimeEditField            matlab.ui.control.NumericEditField
        RunTimeEditFieldLabel       matlab.ui.control.Label
        GraphResetLamp              matlab.ui.control.Lamp
        GraphResetLampLabel         matlab.ui.control.Label
        InitializeButton            matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ValuesReadLamp              matlab.ui.control.Lamp
        ValuesReadLampLabel         matlab.ui.control.Label
        SystemInitializedLamp       matlab.ui.control.Lamp
        ReadValuesButton            matlab.ui.control.Button
        SystemInitializedLampLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Property % Description
        ard = [];
        ultras = [];
        data = [];
        filt = [];
        time = [];
        s = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeHardware(app, event)
            try
                % Create an Arduino object
                app.ard = arduino('COM4', 'Uno', 'Libraries', {'Ultrasonic','Servo'});
                % Create an ultrasonic sensor object
                app.ultras = app.ard.ultrasonic('D7', 'D6');
                % Create servo object
                app.s = app.ard.servo('D9');
                msgbox("Initialized successful");
                app.s.writePosition(0);

                app.PortLabel.Text = 'Port: COM4';
                app.PinsLabel.Text = 'Pins: D7,D6,D9'

            catch
                msgbox('Error initializing');
                app.GraphResetLamp.Color = 'r';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes); %reset should clear the plot completely

            app.data = [];
            app.time = [];

            app.GraphResetLamp.Color = 'g';
        end

        % Button pushed function: ReadValuesButton
        function ReadValues(app, event)
            %using data from previous run
            hytime = [0.0191 0.2685 0.3969 0.5220 0.6499 0.7756 0.8999 1.0322 1.1577 1.2851 1.4107 1.5371 1.6654 1.7899 1.9168 2.0439 2.1713 2.2964 2.4170 2.5479 2.6736 2.7980 2.9260 3.0476 3.1736 3.2979 3.4239 3.5480 3.6740 3.8023 3.9247 4.0504 4.1786 4.3050 4.4317 4.5706 4.6974 4.8207 4.9487 5.0720];
            t = hytime - hytime(1); %adjusts axis so t starts at zero

            y = [.0478 0.0328 0.0328 0.0395 0.0514 0.0655 0.0759 0.0728 0.0524 0.0416 0.0300 0.0322 0.0364 0.0431 0.0534 0.0662 0.0721 0.0867 0.0712 0.0578 0.0479 0.0416 0.0359 0.0353 0.0426 0.0447 0.0566 0.0643 0.0764 0.0852 0.0784 0.0774 0.0643 0.0516 0.0438 0.0353 0.0359 0.0431 0.0453 0.0634];
            new_y = y-mean(y(1)); %adjusting axis so y starts at zero

            % Plotting on the same graph
            plot(app.UIAxes, t, new_y, 'b')

            % Portion of code below finds best-fit line of graph from data.
            % Below the function (y = sin(2pi(t - #) + #) to fit is defined.
            funct = @(p, t) p(1) * sin(2 * pi * p(2)* (t-p(3)))+ p(4);

            % Initial guess for the variables used in anonymous function above:
            initial_guess = [1, 1, 0, 0];

            % Fit the curve:
            y_fit = lsqcurvefit(funct, initial_guess, t, new_y);

            % Actual function that fits the curve:
            syms x;
            y_fit_EQ = y_fit(1)*sin(2*pi*y_fit(2)*(x-y_fit(3))) + y_fit(4);

            % Plots original data and best-fit curve:
            figure;
            plot(t,new_y, 'b-');
            hold on
            plot(t, funct(y_fit, t), 'r--');
            hold off;

            % Asks user to grab two points
            [t, ~] = ginput(2);

            % sorts elements in ascending order
            t = sort(t);

            % extracts datapoint between ginput values
            selected_indices = t(1) <= hytime & hytime <= t(2);
            selected_data_t = hytime(selected_indices);
            selected_data_y = new_y(selected_indices);

            %tell user what the data points in between ginput values are
            disp('Extracted data within the selected region:');
            disp(['Time: ', num2str(selected_data_t)]);
            disp(['y: ', num2str(selected_data_y)]);

            % Find the indices of the two points closest to zero
            [~, idx_closest_to_zero_y] = sort(abs(selected_data_y));


            % Extract the x-values corresponding to the two y-values closest to zero if they exist
            if numel(idx_closest_to_zero_y) >= 2
                initial_guess_one_index = idx_closest_to_zero_y(1);
                initial_guess_two_index = idx_closest_to_zero_y(2);

                initial_guess_left_one = selected_data_t(max(1, initial_guess_one_index - 1))
                initial_guess_left_two = selected_data_t(min(length(selected_data_t), initial_guess_one_index + 1))

                initial_guess_right_one = selected_data_t(max(1, initial_guess_two_index - 1))
                initial_guess_right_two = selected_data_t(min(length(selected_data_t), initial_guess_two_index + 1))

                % Display the initial guesses
                disp(['Initial Guesses for Left Root (x): ', num2str([initial_guess_left_one, initial_guess_left_two])]);
                disp(['Initial Guesses for Right Root (x): ', num2str([initial_guess_right_one, initial_guess_right_two])]);

            end

            coefficients = polyfit(hytime,new_y , 2);

            % Display the coefficients
            disp('The coefficients of the fitted polynomial equation are:');
            disp(coefficients)

            % Create a string representation of the equation
            equationString = sprintf('y = %.4f * t^%d + %.4f * t + %.4f', coefficients(1), 2, coefficients(2), coefficients(3));
            disp('The fitted equation is:' , equationString);


            %secant equation for left root
            f=@(t) coefficients(1)*t^2 +coefficients(2)*t + coefficients(3);
            x1=initial_guess_left_one;
            x2=initial_guess_left_two;
            tolerance=0.005;
            AbsError= inf;
            counter=0;
            %starting loop for secant
            while AbsError>tolerance
                counter=counter+1;
                if counter>=50
                    break;
                end
                x3= x2 - (f(x2)*(x1-x2))/(f(x1)-(x2));
                AbsError= abs((x3-x2)/x3);
                fprintf('new root: %.6f, AbsError = %.6f\n',x3,AbsError);
                x1=x2;
                x_temporary = x3 % Holds value from first zero to be used in calculation of error further below.
                x2=x3;

            end

            % Print final root and final error
            fprintf('Final left root prediction: x3 = %.6f\n', x3);
            fprintf('Final error: Error = %.6f\n', AbsError);

            %secant equation for right root

            f=@(t) coefficients(1)*t^2 +coefficients(2)*t + coefficients(3);
            x1=initial_guess_right_one;
            x2=initial_guess_right_two;
            tolerance=0.005;
            AbsError= inf;
            counter=0;
            %starting loop for secant
            while AbsError>tolerance
                counter=counter+1;
                if counter>=50
                    break;
                end
                x3= x2 - (f(x2)*(x1-x2))/(f(x1)-(x2));
                AbsError= abs((x3-x2)/x3);
                fprintf('new root: %.6f, AbsError = %.6f\n',x3,AbsError);
                x1=x2;
                x2=x3;

            end
            % Print final root and final error
            fprintf('Final right root prediction: x3 = %.6f\n', x3);
            fprintf('Final error: Error = %.6f\n', AbsError);

            % Newton's Divided Difference Method:

            % Estimation of first zero:
            T1_left = initial_guess_left_one;
            T2_left = initial_guess_left_two;

            Y1 = subs(y_fit_EQ, x,T1_left);
            Y2 = subs(y_fit_EQ, x, T2_left);

            b0 = Y1;
            b1 = (Y2 - Y1/(T2_left-T1_left));

            syms Tt;
            f_Tt = b0 + b1*(Tt- T1_left) == 0;

            new_estimation_left = solve(f_Tt, Tt);
            fprintf('The Newton Divided Difference Method estimation for tbe "left" zero is: %f\n', new_estimation_left);

            % Estimation of second zero:
            T1_right = initial_guess_right_one;
            T2_right = initial_guess_right_two;

            Y1 = subs(y_fit_EQ, x,T1_right);
            Y2 = subs(y_fit_EQ, x, T2_right);

            b0 = Y1;
            b1 = (Y2 - Y1/(T2_right-T1_right));

            syms Tt;
            f_Tt = b0 + b1*(Tt- T1_right) == 0;

            new_estimation_right = solve(f_Tt, Tt);
            fprintf('The Newton Divided Difference Method estimation for tbe "right" zero is: %f\n', new_estimation_right);

            % Calculates and outputs the error from the Newton's Divide method and Secant Method:
            fprintf('Error of "left" zero from Newtown Divide and Secant Method is: %f\n', abs(((new_estimation_left - x_temporary)/new_estimation_left)));
            fprintf('Error of "right" zero from Newtown Divide and Secant Method is: %f\n', abs(((new_estimation_right-x3)/new_estimation_right)));

            % Extracting one second of data (adjust this based on your sampling rate)
            oneSecondDataIndex = hytime <= 1.0;
            oneSecondData_t = hytime(oneSecondDataIndex);
            oneSecondData_y = new_y(oneSecondDataIndex);

            % Perform prediction for 0.1 seconds in the future (adjust as needed)
            predictionTime = 1.1;  % Predicting 0.1 seconds in the future
            predictedValue = app.PredictHeartDistance(oneSecondData_t, oneSecondData_y, predictionTime);

            % Plot real and predicted values
            plot(app.UIAxes, oneSecondData_t, oneSecondData_y, 'b-', 'DisplayName', 'Real Values');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, predictionTime, predictedValue, 'ro', 'DisplayName', 'Predicted Value');
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Distance (m)');
            title(app.UIAxes, 'Real vs Predicted Heart Distance');
            legend(app.UIAxes, 'Real Values', 'Predicted Value');

            % Update GUI figure
            drawnow;

            % Output to servo based on the checkbox value
            if app.OutputModeCheckBox.Value
                % Output using predicted value
                app.OutputToServo(predictedValue);
            else
                % Output using current value
                app.OutputToServo(oneSecondData_y(end));
            end
            

        end

        % Value changed function: RunTimeEditField
        function RunTime(app, event)
            value = app.RunTimeEditField.Value; % Allows input for specific time to run

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ultrasound')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Distance(m)')
            zlabel(app.UIAxes, 'Z')
            app.SystemInitializedLampLabel = uilabel(app.UIFigure);
            app.SystemInitializedLampLabel.Text = 'System Initialized';
            app.ReadValuesButton = uibutton(app.UIFigure, 'push');
            app.ReadValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadValues, true);
            app.ReadValuesButton.Text = 'Read Values';
            app.SystemInitializedLamp = uilamp(app.UIFigure);
            app.ValuesReadLampLabel = uilabel(app.UIFigure);
            app.ValuesReadLampLabel.Text = 'Values Read';
            app.ValuesReadLamp = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeHardware, true);
            app.InitializeButton.Text = 'Initialize';
            app.GraphResetLampLabel = uilabel(app.UIFigure);
            app.GraphResetLampLabel.Text = 'Graph Reset';
            app.GraphResetLamp = uilamp(app.UIFigure);
            app.RunTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RunTimeEditFieldLabel.Text = 'Run Time';
            app.RunTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RunTime, true);
            app.PortLabel = uilabel(app.UIFigure);
            app.PortLabel.Text = 'Port';
            app.PinsLabel = uilabel(app.UIFigure);
            app.PinsLabel.Text = 'Pins';
            app.CalibrateGraphButton = uibutton(app.UIFigure, 'push');
            app.CalibrateGraphButton.Text = 'Calibrate Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_NumericalMethod_Implementation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end